classdef SimExp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        ControllerLabel               matlab.ui.control.Label
        EstimatorLabel                matlab.ui.control.Label
        ReferenceLabel                matlab.ui.control.Label
        SensorLabel                   matlab.ui.control.Label
        PlantLabel                    matlab.ui.control.Label
        NumLabel                      matlab.ui.control.Label
        ReloadButton                  matlab.ui.control.Button
        ModeDropDown                  matlab.ui.control.DropDown
        ModeDropDownLabel             matlab.ui.control.Label
        RunmodeListBox                matlab.ui.control.ListBox
        RunmodeListBoxLabel           matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        FilenameoptionEditField       matlab.ui.control.EditField
        FilenameoptionEditFieldLabel  matlab.ui.control.Label
        SavedataButton                matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        TimeSlider                    matlab.ui.control.Slider
        TimeSliderLabel               matlab.ui.control.Label
        DrawgraphButton               matlab.ui.control.Button
        LampLabel                     matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        StartButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


  properties (Access = private)
    fStart = 0;
    fInit  = 0;
    post = @(app) [];
    in_prog = @(app) [];
    dummy_class = @(class) struct("do",@(varargin)[],"result",class.result);
  end
  properties (Access = public)
    data_file_name = [];
    takeoff_ref = [];
    landing_ref = [];
    flight_sensor = [];
    flight_reference = [];
    flight_estimator = [];
    flight_controller = [];
    flight_input_transform = [];
    fDebug
    PInterval
    fExp = 0;
    mode = [];
    logger
    env = [];
    time
    cha = "";
    cha0 = "";
    agent
    motive = [];
    update_timer;
    N = 2;
    t0 = 0;
  end
  methods (Access = private)
    function mytimer_fun(app, obj, event)
      % Timer で指定するコールバック関数
      if app.t0 == app.time.t && app.time.t > 0
        app.Label_2.Text = ["","","===========  Emergency stop!  ========="];
        app.StopProp;
        app.fStart = 0;
      end
      app.t0 = app.time.t;
    end
    function reset_app(app)
      app.data_file_name = [];
      app.takeoff_ref = [];
      app.landing_ref = [];
      app.logger = [];
      app.env = [];
      app.time = [];
      app.cha = "";
      app.cha0 = "";
      app.agent = [];
      app.motive = [];
      app.update_timer = [];
      app.N = 2;
    end
    function Arming(app)
        for i = 1:app.N
            app.agent(i).plant.arming;
            app.agent(i).estimator = app.flight_estimator(i).value;
            app.agent(i).controller = app.flight_controller(i).value;
        end
    end
    function Takeoff(app)
        for i = 1:app.N
          app.agent(i).estimator = app.flight_estimator(i).value;
          app.agent(i).controller = app.flight_controller(i).value;
          app.agent(i).input_transform = app.flight_input_transform(i).value;
          app.agent(i).reference = app.takeoff_ref(i).value;
        end
    end
    function Flight(app)
        for i = 1:app.N
            app.agent(i).estimator = app.flight_estimator(i).value;
            app.agent(i).controller = app.flight_controller(i).value;
            app.agent(i).input_transform = app.flight_input_transform(i).value;
            app.agent(i).reference = app.flight_reference(i).value;
        end
    end
    function Landing(app)
        for i = 1:app.N
          app.agent(i).estimator = app.flight_estimator(i).value;
          app.agent(i).controller = app.flight_controller(i).value;
          app.agent(i).input_transform = app.flight_input_transform(i).value;
          app.agent(i).reference = app.landing_ref(i).value;
        end
    end
    function StopProp(app)
        for i =1:app.N
      app.agent(i).plant.stop;
        end
    end
    function Quit(app)
        for i = 1:app.N
      app.agent(i).plant.stop;
        end
    end
    function do_calculation(app,tStart)
      for i = 1:app.N
        app.agent(i).sensor.do(app.time,app.cha,app.logger,app.env,app.agent(i),i);
        % disp(string(i)+":s_ok"+string(toc(tStart)))
        app.agent(i).estimator.do(app.time,app.cha,app.logger,app.env,app.agent(i),i);
        % disp(string(i)+":e_ok"+string(toc(tStart)))
        app.agent(i).reference.do(app.time,app.cha,app.logger,app.env,app.agent(i),i);
        % disp(string(i)+":r_ok"+string(toc(tStart)))
        app.agent(i).controller.do(app.time,app.cha,app.logger,app.env,app.agent(i),i);
        % disp(string(i)+":c_ok"+string(toc(tStart)))
        app.agent(i).input_transform.do(app.time,app.cha,app.logger,app.env,app.agent(i),i);
        % disp(string(i)+":input_ok"+string(toc(tStart)))
        app.agent(i).plant.do(app.time,app.cha,app.logger,app.env,app.agent(i),i);
        % disp(string(i)+":plant_ok"+string(toc(tStart)))
      end
      % if mod(app.N,2) ==1 
      %   app.logger.logging(app.time, app.cha, app.agent(2:end),[]);
      % else
        app.logger.logging(app.time, app.cha, app.agent,[]);
        % disp("logger_ok"+string(toc(tStart)))
      % end
      app.time.k = app.logger.k;
    end

    function loop(app)
      pause(0.1);
      if app.fExp % experiment
        app.Label.Text = ["== EXPERIMENT ==","Start! Press key code" + app.cha,"s : stop", "a : arming", "t : take-off", "f : fligh", "l : landing"];
        app.TimeSliderLabel.Text = ["..."];
        uiwait(app.UIFigure)
        start(app.update_timer);
        % Caution : N agents are not allowed.
        while app.fStart
          tStart = tic;
          drawnow
          if app.cha0 ~= app.cha % exec once per another key press
            switch app.cha
              case {'q',' '}
                app.Quit;
                app.LampLabel.Text = "Quit the trial";
                app.Lamp.Color = [0 0 0];
                break;
              case 's'
                app.StopProp;
                app.LampLabel.Text = "Stop propellas";
                app.Lamp.Color = [0 1 0];
                pause(1)
              case 'f'
                if strcmp(app.cha0,'t') % "flight" is allowed after "take-off"
                  app.LampLabel.Text = "Flight";
                  app.Flight;
                end
              case 'l'
                app.LampLabel.Text = "Landing";
                app.Landing;
              case 't'
                if strcmp(app.cha0,'a') % "take-off" is allowed after "arming"
                  app.LampLabel.Text = "Take-off";
                  app.Takeoff;
                end
              case 'a'
                app.LampLabel.Text = "Arming";
                app.Arming;
                app.Lamp.Color = [1 0 0];
                pause(1)
                otherwise
                    disp(app.cha);
            end
            app.cha0 = app.cha;
          end
          if ~isempty(app.motive);              app.motive.getData(app.agent);            end
          %app.TimeSlider.Value = app.time.t;          
          app.do_calculation(tStart);
          app.time.dt = toc(tStart);
          disp(app.cha+" phase, t="+string(app.time.t)+"(s), dt="+string(app.time.dt)+"(s)")
          app.time.t = app.time.t + app.time.dt;
        end
      else % simulation
        app.Label.Text = "Press any key to start. (except for 'q', 'space', 'enter')";
        uiwait(app.UIFigure)
        %start(app.update_timer);
        while app.fStart && (app.time.t < app.time.te)
          drawnow;
          tStart = tic;
          if app.cha0 ~= app.cha
            switch app.cha
              case {'q',' '}
                app.LampLabel.Text = "Quit the trial";
                app.Lamp.Color = [1 0 0];
                break;
              case 'l'
                app.LampLabel.Text = "Landing";
                app.Landing;
              case 't'
                app.LampLabel.Text = "Take-off";
                app.Takeoff;
              case 'f'
                app.LampLabel.Text = "Flight";
                app.Flight;
              otherwise
                app.LampLabel.Text = app.cha;
            end
            app.cha0 = app.cha;
          end
          if ~isempty(app.motive)
            app.motive.getData(app.agent);
          end
          app.do_calculation(tStart);
          app.TimeSlider.Value = app.time.t;
          app.TimeSliderLabel.Text = string(app.time.t);
          app.time.t = app.time.t + app.time.dt;
          if app.fDebug && app.time.t > app.time.dt % not active at the initial step
            app.in_prog(app);
          end
        end
      end
      app.stop;
    end

    function keyPressFunc(app,src,event)
      app.cha = event.Character;
      uiresume(src);
    end
    function stop(app)
      app.fStart = 0;
      app.StartButton.Text = "Start";
      app.Lamp.Color = [0 0 0];
      app.Label.Text = "Program Stop";
      app.cha = "";
      app.cha0 = "";
      if ~isempty(app.update_timer)
        stop(app.update_timer);
        delete(app.update_timer);
        app.update_timer = [];
      end
    end
    %%
    function set_mode(app)
      app.reset_app;
      run(app.mode);
      if exist("do_calculation","var")
        app.do_calculation = @(A) do_calculation(A);
      else
        app.agent = agent;
        app.N = length(agent); app.NumLabel.Text = "Num : " + string(app.N);
        for i = 1:app.N
            app.PlantLabel.Text = ["Plant : "+  class(agent(i).plant)];
            app.EstimatorLabel.Text = ["Estimator : "+class(agent(i).estimator), "Param : "+class(agent(i).parameter)];
            app.SensorLabel.Text = ["Sensor : "+  class(agent(i).sensor)];
            app.ReferenceLabel.Text = ["Reference : "+ class(agent(i).reference)];
            app.ControllerLabel.Text = ["Controller : "+ class(agent(i).controller)];
            app.logger = logger;
        end
        if app.fExp
            for i = 1:app.N
              %modeファイルでの設定を保持してほかで上書きされても戻せるようにする
              app.flight_reference(i).value = agent(i).reference;
              app.flight_estimator(i).value = agent(i).estimator;
              app.flight_controller(i).value = agent(i).controller;
              app.flight_input_transform(i).value = agent(i).input_transform;
              app.agent(i).estimator = app.dummy_class(agent(i).estimator);
              app.agent(i).reference = app.dummy_class(agent(i).reference);
              app.agent(i).controller = app.dummy_class(agent(i).controller);
            end
        else
            for i = 1:app.N
              %modeファイルでの設定を保持してほかで上書きされても戻せるようにする
              app.flight_reference(i).value = agent(i).reference;
              app.flight_estimator(i).value = agent(i).estimator;
              app.flight_controller(i).value = agent(i).controller;
              app.flight_input_transform(i).value = agent(1).input_transform;
              app.agent(i).estimator = app.dummy_class(agent(i).estimator);
              app.agent(i).reference = app.dummy_class(agent(i).reference);
              app.agent(i).controller = app.dummy_class(agent(i).controller);
            end
        end
        if exist("env","var") app.env = env; end
        if exist("in_prog_func","var") app.in_prog = in_prog_func;      end
        if exist("post_func","var") app.post = post_func;      end
        if exist("takeoff_ref","var") 
            for i = 1:app.N
                app.takeoff_ref(i).value = takeoff_ref{i};
            end
        end
        if exist("landing_ref","var") 
            for i = 1:app.N
                app.landing_ref(i).value = landing_ref{i};
            end
        end
      end
      app.time = time;
      app.TimeSlider.Value = 0;
      app.TimeSlider.Limits = [time.ts time.te];
      app.TimeSlider.MajorTicks = time.ts:(time.te-time.ts)/5:time.te;
      if exist("motive","var"); app.motive = motive; end
      app.clear_axes;
      app.TimeSliderLabel.Text = ["time : " + app.time.t];
    end

    function clear_axes(app)
      cla(app.UIAxes); if ~isempty(app.UIAxes.Colorbar) app.UIAxes.Colorbar.Visible = 'off';end
      cla(app.UIAxes2);
      cla(app.UIAxes3);
      cla(app.UIAxes4);
    end
  end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fExp, fDebug, PInterval)
      % start up function : set modes list and load the first mode
      %app.UIFigure.WindowState = 'maximized';
      app.fExp = fExp;
      app.fDebug = fDebug;
      app.PInterval = PInterval;
      if app.fExp
        app.RunmodeListBox.Value = "Experiment";
        app.ModeDropDown.Items = evalin('base','ExpBaseMode');
      else
        app.RunmodeListBox.Value = "Simulation";
        app.ModeDropDown.Items = evalin('base','SimBaseMode');
      end
      app.stop
      if ~isempty(app.ModeDropDown.Items{2})
        % automatically load the first mode
        app.mode = app.ModeDropDown.Items{2};
        app.set_mode;
      end
      % appearance
      app.LampLabel.HorizontalAlignment = 'left';
        end

        % Button pushed function: StartButton
        function start(app, event)
      app.Label.VerticalAlignment = 'top';
      app.UIFigure.WindowKeyPressFcn = @app.keyPressFunc;
      app.Label_2.Text = ""; % clear emergency text
      if ~isempty(app.mode)
        if app.fStart % to stop
          app.stop;
        else % to start
          app.fStart = 1;
          app.StartButton.Text = "Stop";
          app.Lamp.Color = [0 1 0];
          app.update_timer = timer('Period', app.PInterval,... % poling interval
            'ExecutionMode', 'fixedSpacing', ... % execution mode
            'TasksToExecute', Inf, ... % trial number
            'TimerFcn', @app.mytimer_fun); % callback function

          app.loop();
        end
      else
        app.Label.Text = "Set mode first";
        app.stop;
      end
        end

        % Value changed function: RunmodeListBox
        function switch_run_mode(app, event)
      value = app.RunmodeListBox.Value;
      app.Lamp.Color = [0 0 0];
      if strcmp(value,"Experiment")
        app.fExp = 1;
        app.ModeDropDown.Items = evalin('base','ExpBaseMode');
      else
        app.fExp = 0;
        app.ModeDropDown.Items = evalin('base','SimBaseMode');
      end
      app.stop
        end

        % Value changed function: ModeDropDown
        function change_mode(app, event)
      app.mode = app.ModeDropDown.Value;
      app.set_mode;
        end

        % Value changing function: TimeSlider
        function set_current_time(app, event)
      app.time.k = find(app.logger.Data.t>=event.Value,1);
      if isempty(app.time.k)
        app.time.k = app.logger.k;
      end
      app.time.t = app.logger.Data.t(app.time.k);
      app.draw_data;
        end

        % Button pushed function: DrawgraphButton
        function draw_data(app, event)
      app.clear_axes;
      if isempty(app.post)
        app.logger.plot({1:app.N, "p", "er"},"FH",app.UIAxes,"xrange",[app.time.ts,app.time.t]);
        app.logger.plot({1:app.N, "q", "er"},"FH",app.UIAxes2,"xrange",[app.time.ts,app.time.t]);
        app.logger.plot({1:app.N, "input", ""},"FH",app.UIAxes3,"xrange",[app.time.ts,app.time.t]);
      else
        app.post(app);
      end
        end

        % Button pushed function: ReloadButton
        function reload_mode(app, event)
            if app.fExp
              for i = 1:length(app.agent)
                delete(app.agent(i).plant.connector.serial)
              end
            end
          app.set_mode;
        end

        % Value changed function: FilenameoptionEditField
        function set_file_name(app, event)
      app.data_file_name = app.FilenameoptionEditField.Value;
        end

        % Button pushed function: SavedataButton
        function save_data(app, event)
      app.logger.save(app.data_file_name);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {643, 643, 643};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {643, 643};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {187, '1x', 323};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunmodeListBoxLabel = uilabel(app.LeftPanel);
            app.RunmodeListBoxLabel.Text = 'Run mode';
            app.RunmodeListBox = uilistbox(app.LeftPanel);
            app.RunmodeListBox.Items = {'Experiment', 'Simulation'};
            app.RunmodeListBox.ValueChangedFcn = createCallbackFcn(app, @switch_run_mode, true);
            app.RunmodeListBox.Value = 'Simulation';
            app.ModeDropDownLabel = uilabel(app.LeftPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.LeftPanel);
            app.ModeDropDown.Items = {'Default'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @change_mode, true);
            app.ModeDropDown.Value = 'Default';
            app.ReloadButton = uibutton(app.LeftPanel, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @reload_mode, true);
            app.ReloadButton.Text = 'Reload';
            app.NumLabel = uilabel(app.LeftPanel);
            app.NumLabel.Text = 'Num : ';
            app.PlantLabel = uilabel(app.LeftPanel);
            app.PlantLabel.Text = 'Plant';
            app.SensorLabel = uilabel(app.LeftPanel);
            app.SensorLabel.Text = 'Sensor';
            app.ReferenceLabel = uilabel(app.LeftPanel);
            app.ReferenceLabel.Text = 'Reference';
            app.EstimatorLabel = uilabel(app.LeftPanel);
            app.EstimatorLabel.Text = 'Estimator';
            app.ControllerLabel = uilabel(app.LeftPanel);
            app.ControllerLabel.Text = 'Controller';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.CenterPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.StartButton.Text = 'Start';
            app.Lamp = uilamp(app.CenterPanel);
            app.LampLabel = uilabel(app.CenterPanel);
            app.LampLabel.Text = 'Lamp';
            app.DrawgraphButton = uibutton(app.CenterPanel, 'push');
            app.DrawgraphButton.ButtonPushedFcn = createCallbackFcn(app, @draw_data, true);
            app.DrawgraphButton.Text = 'Draw graph';
            app.TimeSliderLabel = uilabel(app.CenterPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.CenterPanel);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @set_current_time, true);
            app.Label_2 = uilabel(app.CenterPanel);
            app.Label_2.Text = '';
            app.SavedataButton = uibutton(app.CenterPanel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @save_data, true);
            app.SavedataButton.Text = 'Save data';
            app.FilenameoptionEditFieldLabel = uilabel(app.CenterPanel);
            app.FilenameoptionEditFieldLabel.Text = 'File name (option)';
            app.FilenameoptionEditField = uieditfield(app.CenterPanel, 'text');
            app.FilenameoptionEditField.ValueChangedFcn = createCallbackFcn(app, @set_file_name, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimExp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end