classdef FireSimulator < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                        matlab.ui.Figure
    GridLayout                      matlab.ui.container.GridLayout
    LeftPanel                       matlab.ui.container.Panel
    nyEditField                     matlab.ui.control.NumericEditField
    nyEditFieldLabel                matlab.ui.control.Label
    nxEditField                     matlab.ui.control.NumericEditField
    nxEditFieldLabel                matlab.ui.control.Label
    mapsizeLabel                    matlab.ui.control.Label
    ShapefileLabel                  matlab.ui.control.Label
    WinddataEditField               matlab.ui.control.EditField
    WinddataEditFieldLabel          matlab.ui.control.Label
    SimstepEditField                matlab.ui.control.NumericEditField
    SimstepEditFieldLabel           matlab.ui.control.Label
    FirefightersEditField           matlab.ui.control.NumericEditField
    FirefightersEditFieldLabel      matlab.ui.control.Label
    SpreadingEditField_2            matlab.ui.control.EditField
    SpreadingEditField_2Label       matlab.ui.control.Label
    FlyingEditField_2               matlab.ui.control.EditField
    FlyingEditField_2Label          matlab.ui.control.Label
    CheckBox                        matlab.ui.control.CheckBox
    windaverageCheckBox             matlab.ui.control.CheckBox
    NorthWindDir                    matlab.ui.control.UIAxes
    RightPanel                      matlab.ui.container.Panel
    TabGroup                        matlab.ui.container.TabGroup
    SettingTab                      matlab.ui.container.Tab
    IgnitionpointredEditFieldLabel  matlab.ui.control.Label
    IgnitionpointredEditField       matlab.ui.control.EditField
    SetmapButton                    matlab.ui.control.Button
    MaprotationCCWEditField         matlab.ui.control.EditField
    MaprotationCCWEditFieldLabel    matlab.ui.control.Label
    ToprightblueEditFieldLabel      matlab.ui.control.Label
    ToprightblueEditField           matlab.ui.control.EditField
    BottomleftgreenEditField        matlab.ui.control.EditField
    BottomleftgreenEditFieldLabel   matlab.ui.control.Label
    original_data_ax                matlab.ui.control.UIAxes
    SimulationTab                   matlab.ui.container.Tab
    MainfigureswitcherLabel         matlab.ui.control.Label
    WeightButton                    matlab.ui.control.StateButton
    FirestatusButton                matlab.ui.control.StateButton
    SaveLogButton                   matlab.ui.control.Button
    SaveWButton                     matlab.ui.control.Button
    InitializeButton                matlab.ui.control.Button
    StartButton                     matlab.ui.control.StateButton
    InitializefirstLamp             matlab.ui.control.Lamp
    InitializefirstLampLabel        matlab.ui.control.Label
    TimeSlider                      matlab.ui.control.Slider
    TimeSliderLabel                 matlab.ui.control.Label
    Grid_ax                         matlab.ui.control.UIAxes
    Spread_ax                       matlab.ui.control.UIAxes
    Flying_ax                       matlab.ui.control.UIAxes
  end

  % Properties that correspond to apps with auto-reflow
  properties (Access = private)
    onePanelWidth = 576;
  end


  properties (Access = public)    
    step_end
    unum
    map
    logger
    wind_data
    roibl % bottom left
    roitr % top right
    roifire % fire starting point
    shape_ratio % second/first of app.original_data_ax.PlotBoxAspectRatio
    ps % selected region [p1;p2;p3;p4]
    shape_min % minimum position in shape data
    outbreak % fire outbreak grid points [x1,y1;x2,y2;...];
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, flag, shape_data, shape_opts, W, unum, step_end, wind_data)
      arguments
        app
        flag
        shape_data
        shape_opts
        W = [];
        unum = 0;
        step_end = 100;
        wind_data = [];
      end
      constructor(app, flag, shape_data, shape_opts, W, unum, step_end, wind_data);
    end

    % Button pushed function: InitializeButton
    function initialize_simulation(app, event)
      initialize_callback(app,event);
    end

    % Value changed function: StartButton
    function Start(app, event)
      start_callback(app,event);
    end

    % Value changed function: MaprotationCCWEditField
    function MaprotationCCWEditFieldValueChanged(app, event)
      map_rotation_CCW(app,event);
    end

    % Value changed function: BottomleftgreenEditField
    function BottomleftgreenEditFieldValueChanged(app, event)
      app.MaprotationCCWEditFieldValueChanged(0);
    end

    % Value changed function: ToprightblueEditField
    function ToprightblueEditFieldValueChanged(app, event)
      app.MaprotationCCWEditFieldValueChanged(0);
    end

    % Button pushed function: SetmapButton
    function SetmapButtonPushed(app, event)
      set_map_callback(app,event);
    end

    % Value changed function: nxEditField
    function nxEditFieldValueChanged(app, event)
      app.map.nx = app.nxEditField.Value;
      app.map.plot_W(app.Grid_ax);      
    end

    % Value changed function: nyEditField
    function nyEditFieldValueChanged(app, event)
      app.map.ny = app.nyEditField.Value;
      app.map.plot_W(app.Grid_ax);      
    end

    % Button pushed function: SaveWButton
    function SaveWButtonPushed(app, event)
      save_W_callback(app,event);
    end

    % Button pushed function: SaveLogButton
    function SaveLogButtonPushed(app, event)
      save_log();
    end

    % Value changed function: FlyingEditField_2
    function FlyingEditField_2ValueChanged(app, event)
      app.map.flag.nf = double(split(string(app.FlyingEditField_2.Value),','));      
    end

    % Value changed function: SpreadingEditField_2
    function SpreadingEditField_2ValueChanged(app, event)
      app.map.flag.ns = double(split(string(app.SpreadingEditField_2.Value),','));
    end

    % Callback function: not associated with a component
    function SwitchmainviewButtonGroupSelectionChanged(app, event)
      selectedButton = app.SwitchmainviewButtonGroup.SelectedObject;
      switch selectedButton
        case 'GridweightBottun'
          app.map.plot_W(app.Grid_ax);
        case 'ResetButton'
          delete(app.NorthWindDir.Children);
          app.map.model_init(app.outbreak);
        case 'FireStatusButton'
          app.map.draw_state(app.map,app.Grid_ax);
      end
    end

    % Callback function: not associated with a component
    function SwitchmainviewButtonGroupButtonDown(app, event)
       app.map.plot_W(app.Grid_ax);
    end

    % Value changed function: WeightButton
    function WeightButtonValueChanged(app, event)
       app.map.plot_W(app.Grid_ax);
    end

    % Value changed function: FirestatusButton
    function FirestatusButtonValueChanged(app, event)
      app.map.draw_state(app.map,app.Grid_ax);
    end

    % Changes arrangement of the app based on UIFigure width
    function updateAppLayout(app, event)
      currentFigureWidth = app.UIFigure.Position(3);
      if(currentFigureWidth <= app.onePanelWidth)
          % Change to a 2x1 grid
          app.GridLayout.RowHeight = {565, 565};
          app.GridLayout.ColumnWidth = {'1x'};
          app.RightPanel.Layout.Row = 2;
          app.RightPanel.Layout.Column = 1;
      else
          % Change to a 1x2 grid
          app.GridLayout.RowHeight = {'1x'};
          app.GridLayout.ColumnWidth = {220, '1x'};
          app.RightPanel.Layout.Row = 1;
          app.RightPanel.Layout.Column = 2;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      colormap(app.UIFigure, 'parula');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
      app.GridLayout = uigridlayout(app.UIFigure);
      app.LeftPanel = uipanel(app.GridLayout);
      app.NorthWindDir = uiaxes(app.LeftPanel);
      app.windaverageCheckBox = uicheckbox(app.LeftPanel);
      app.windaverageCheckBox.Text = 'wind average';
      app.CheckBox = uicheckbox(app.LeftPanel);
      app.FlyingEditField_2Label = uilabel(app.LeftPanel);
      app.FlyingEditField_2Label.Text = 'Flying';
      app.FlyingEditField_2 = uieditfield(app.LeftPanel, 'text');
      app.FlyingEditField_2.ValueChangedFcn = createCallbackFcn(app, @FlyingEditField_2ValueChanged, true);
      app.FlyingEditField_2.Value = '0.075,1.2,1.5';
      app.SpreadingEditField_2Label = uilabel(app.LeftPanel);
      app.SpreadingEditField_2Label.Text = 'Spreading';
      app.SpreadingEditField_2 = uieditfield(app.LeftPanel, 'text');
      app.SpreadingEditField_2.ValueChangedFcn = createCallbackFcn(app, @SpreadingEditField_2ValueChanged, true);
      app.SpreadingEditField_2.Value = '0.07,0.3,2.4';
      app.FirefightersEditFieldLabel = uilabel(app.LeftPanel);
      app.FirefightersEditFieldLabel.Text = 'Fire fighters';
      app.FirefightersEditField = uieditfield(app.LeftPanel, 'numeric');
      app.FirefightersEditField.Value = 1;
      app.SimstepEditFieldLabel = uilabel(app.LeftPanel);
      app.SimstepEditFieldLabel.Text = 'Sim step';
      app.SimstepEditField = uieditfield(app.LeftPanel, 'numeric');
      app.SimstepEditField.Value = 240;
      app.WinddataEditFieldLabel = uilabel(app.LeftPanel);
      app.WinddataEditFieldLabel.Text = 'Wind data';
      app.WinddataEditField = uieditfield(app.LeftPanel, 'text');
      app.WinddataEditField.Value = 'data_ito.csv';
      app.ShapefileLabel = uilabel(app.LeftPanel);
      app.ShapefileLabel.Text = 'Shape file : ';
      app.mapsizeLabel = uilabel(app.LeftPanel);
      app.mapsizeLabel.Text = 'map size : ';
      app.nxEditFieldLabel = uilabel(app.LeftPanel);
      app.nxEditFieldLabel.Text = 'nx';
      app.nxEditField = uieditfield(app.LeftPanel, 'numeric');
      app.nxEditField.ValueChangedFcn = createCallbackFcn(app, @nxEditFieldValueChanged, true);
      app.nyEditFieldLabel = uilabel(app.LeftPanel);
      app.nyEditFieldLabel.Text = 'ny';
      app.nyEditField = uieditfield(app.LeftPanel, 'numeric');
      app.nyEditField.ValueChangedFcn = createCallbackFcn(app, @nyEditFieldValueChanged, true);
      app.RightPanel = uipanel(app.GridLayout);
      app.TabGroup = uitabgroup(app.RightPanel);
      app.SettingTab = uitab(app.TabGroup);
      app.SettingTab.Title = 'Setting';
      app.original_data_ax = uiaxes(app.SettingTab);
      title(app.original_data_ax, 'Title')
      xlabel(app.original_data_ax, 'X')
      ylabel(app.original_data_ax, 'Y')
      zlabel(app.original_data_ax, 'Z')
      app.BottomleftgreenEditFieldLabel = uilabel(app.SettingTab);
      app.BottomleftgreenEditFieldLabel.Text = 'Bottom left (green)';
      app.BottomleftgreenEditField = uieditfield(app.SettingTab, 'text');
      app.BottomleftgreenEditField.ValueChangedFcn = createCallbackFcn(app, @BottomleftgreenEditFieldValueChanged, true);
      app.ToprightblueEditField = uieditfield(app.SettingTab, 'text');
      app.ToprightblueEditField.ValueChangedFcn = createCallbackFcn(app, @ToprightblueEditFieldValueChanged, true);
      app.ToprightblueEditFieldLabel = uilabel(app.SettingTab);
      app.ToprightblueEditFieldLabel.Text = 'Top right (blue)';
      app.MaprotationCCWEditFieldLabel = uilabel(app.SettingTab);
      app.MaprotationCCWEditFieldLabel.Text = 'Map rotation (CCW)';
      app.MaprotationCCWEditField = uieditfield(app.SettingTab, 'text');
      app.MaprotationCCWEditField.ValueChangedFcn = createCallbackFcn(app, @MaprotationCCWEditFieldValueChanged, true);
      app.MaprotationCCWEditField.Value = '0';
      app.SetmapButton = uibutton(app.SettingTab, 'push');
      app.SetmapButton.ButtonPushedFcn = createCallbackFcn(app, @SetmapButtonPushed, true);
      app.SetmapButton.Text = 'Set map';
      app.IgnitionpointredEditField = uieditfield(app.SettingTab, 'text');
      app.IgnitionpointredEditFieldLabel = uilabel(app.SettingTab);
      app.IgnitionpointredEditFieldLabel.Text = 'Ignition point (red)';
      app.SimulationTab = uitab(app.TabGroup);
      app.SimulationTab.Title = 'Simulation';
      app.Flying_ax = uiaxes(app.SimulationTab);
      title(app.Flying_ax, 'Flying fire')
      zlabel(app.Flying_ax, 'Z')
      app.Spread_ax = uiaxes(app.SimulationTab);
      title(app.Spread_ax, 'Spreading fire')
      zlabel(app.Spread_ax, 'Z')
      app.Grid_ax = uiaxes(app.SimulationTab);
      title(app.Grid_ax, 'Title')
      xlabel(app.Grid_ax, 'X')
      ylabel(app.Grid_ax, 'Y')
      zlabel(app.Grid_ax, 'Z')
      app.TimeSliderLabel = uilabel(app.SimulationTab);
      app.TimeSliderLabel.Text = 'Time';
      app.TimeSlider = uislider(app.SimulationTab);
      app.InitializefirstLampLabel = uilabel(app.SimulationTab);
      app.InitializefirstLampLabel.Text = 'Initialize first';
      app.InitializefirstLamp = uilamp(app.SimulationTab);
      app.StartButton = uibutton(app.SimulationTab, 'state');
      app.StartButton.ValueChangedFcn = createCallbackFcn(app, @Start, true);
      app.StartButton.Text = 'Start';
      app.InitializeButton = uibutton(app.SimulationTab, 'push');
      app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @initialize_simulation, true);
      app.InitializeButton.Text = 'Initialize';
      app.SaveWButton = uibutton(app.SimulationTab, 'push');
      app.SaveWButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWButtonPushed, true);
      app.SaveWButton.Text = 'Save W';
      app.SaveLogButton = uibutton(app.SimulationTab, 'push');
      app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
      app.SaveLogButton.Text = 'Save Log';
      app.FirestatusButton = uibutton(app.SimulationTab, 'state');
      app.FirestatusButton.ValueChangedFcn = createCallbackFcn(app, @FirestatusButtonValueChanged, true);
      app.FirestatusButton.Text = 'Fire status';
      app.WeightButton = uibutton(app.SimulationTab, 'state');
      app.WeightButton.ValueChangedFcn = createCallbackFcn(app, @WeightButtonValueChanged, true);
      app.WeightButton.Text = 'Weight';
      app.MainfigureswitcherLabel = uilabel(app.SimulationTab);
      app.MainfigureswitcherLabel.Text = 'Main figure switcher';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = FireSimulator(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end