classdef FireSimulation < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                        matlab.ui.Figure
    GridLayout                      matlab.ui.container.GridLayout
    LeftPanel                       matlab.ui.container.Panel
    nyEditField                     matlab.ui.control.NumericEditField
    nyEditFieldLabel                matlab.ui.control.Label
    nxEditField                     matlab.ui.control.NumericEditField
    nxEditFieldLabel                matlab.ui.control.Label
    mapsizeLabel                    matlab.ui.control.Label
    ShapefileLabel                  matlab.ui.control.Label
    WinddataEditField               matlab.ui.control.EditField
    WinddataEditFieldLabel          matlab.ui.control.Label
    SimstepEditField                matlab.ui.control.NumericEditField
    SimstepEditFieldLabel           matlab.ui.control.Label
    FirefightersEditField           matlab.ui.control.NumericEditField
    FirefightersEditFieldLabel      matlab.ui.control.Label
    NorthdirectionGauge             matlab.ui.control.SemicircularGauge
    SpreadingEditField_2            matlab.ui.control.EditField
    SpreadingEditField_2Label       matlab.ui.control.Label
    FlyingEditField_2               matlab.ui.control.EditField
    FlyingEditField_2Label          matlab.ui.control.Label
    CheckBox                        matlab.ui.control.CheckBox
    windaverageCheckBox             matlab.ui.control.CheckBox
    RightPanel                      matlab.ui.container.Panel
    TabGroup                        matlab.ui.container.TabGroup
    SettingTab                      matlab.ui.container.Tab
    IgnitionpointredEditFieldLabel  matlab.ui.control.Label
    IgnitionpointredEditField       matlab.ui.control.EditField
    SetmapButton                    matlab.ui.control.Button
    MaprotationCCWEditField         matlab.ui.control.EditField
    MaprotationCCWEditFieldLabel    matlab.ui.control.Label
    ToprightblueEditFieldLabel      matlab.ui.control.Label
    ToprightblueEditField           matlab.ui.control.EditField
    BottomleftgreenEditField        matlab.ui.control.EditField
    BottomleftgreenEditFieldLabel   matlab.ui.control.Label
    original_data_ax                matlab.ui.control.UIAxes
    SimulationTab                   matlab.ui.container.Tab
    InitializeButton                matlab.ui.control.Button
    StartButton                     matlab.ui.control.StateButton
    InitializefirstLamp             matlab.ui.control.Lamp
    InitializefirstLampLabel        matlab.ui.control.Label
    TimeSlider                      matlab.ui.control.Slider
    TimeSliderLabel                 matlab.ui.control.Label
    SwitchmainviewButtonGroup       matlab.ui.container.ButtonGroup
    Button3                         matlab.ui.control.ToggleButton
    GridweightButton                matlab.ui.control.ToggleButton
    FireStatusButton                matlab.ui.control.ToggleButton
    UIAxes                          matlab.ui.control.UIAxes
    UIAxes3                         matlab.ui.control.UIAxes
    UIAxes2                         matlab.ui.control.UIAxes
  end

  % Properties that correspond to apps with auto-reflow
  properties (Access = private)
    onePanelWidth = 576;
  end


  properties (Access = public)    
    step_end
    unum
    map
    logger
    wind_data
    roibl % bottom left
    roitr % top right
    roifire % fire starting point
    shape_ratio % second/first of app.original_data_ax.PlotBoxAspectRatio
    ps % selected region [p1;p2;p3;p4]
    shape_min % minimum position in shape data
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, flag, shape_data, shape_opts, W, unum, step_end, wind_data)
      arguments
        app
        flag
        shape_data
        shape_opts
        W = [];
        unum = 0;
        step_end = 100;
        wind_data = [];
      end
      % north_dir = 0; % rad
      % start_point = [0,0]; % マップ左下から見た位置 [m,m]
      % map_size = [1,1000]; % north_dir で回転した後の start_pointからの領域 [m m]
      app.NorthdirectionGauge.Visible = 'off';
      app.map = FIELD_MAP(flag,shape_data,shape_opts,W);
      app.ShapefileLabel.Text = string(app.ShapefileLabel.Text) + app.map.shape_data;
      app.mapsizeLabel.Text = string(app.mapsizeLabel.Text) + join(string(shape_opts.map_size)," x ");
      app.map.unum = unum;
      app.step_end = step_end;
      app.wind_data = wind_data;

      app.WinddataEditField.Value = wind_data;
      app.ShapefileLabel.Text = app.map.shape_data;
      app.SimstepEditField.Value = step_end;
      app.FirefightersEditField.Value = unum;
      if isempty(app.map.W) % set region from shape file
        mapshow(app.original_data_ax,app.map.S);
        daspect(app.original_data_ax,[1,1,1]);        
        xm = min([app.map.S.X]);
        ym = min([app.map.S.Y]);
        xM = max([app.map.S.X]);
        yM = max([app.map.S.Y]);
        app.shape_min = [xm,ym];        
        tmp = app.original_data_ax.PlotBoxAspectRatio;
        app.shape_ratio = tmp(2)/tmp(1);
%        patch(app.original_data_ax,'Faces',[1,2,3,4],'Vertices',[xm,ym;xM,ym;xM,yM;xm,yM],...
%          'EdgeColor','green','FaceColor','none','LineWidth',2);
        app.original_data_ax.XLim = [xm-0.01*(xM-xm),xM+0.01*(xM-xm)];
        app.original_data_ax.YLim = [ym-0.01*(yM-ym),yM+0.01*(yM-ym)];
        app.original_data_ax.Title.String = app.map.shape_data;
        %view(app.original_data_ax,atan(tan(shape_opts.north_dir)*app.shape_ratio)*180/pi,90);
        % viewがaspectを変更する前のaxesでの回転を表すため変換が必要
        app.TabGroup.SelectedTab = app.SettingTab;
        if isfield(shape_opts,"start_point")
          xm = xm + shape_opts.start_point(1);
          ym = ym + shape_opts.start_point(2);
          if isfield(shape_opts,"map_size")             
            th = -shape_opts.north_dir;
            tmp = [xm,ym] + shape_opts.map_size*[cos(th),sin(th);-sin(th),cos(th)];
            xM = tmp(1);
            yM = tmp(2);
          end
        end
        app.roibl = drawpoint(app.original_data_ax,'Color','g','Position',[xm,ym]);
        app.roitr = drawpoint(app.original_data_ax,'Color','b','Position',[xM,yM]);
        app.roifire = drawpoint(app.original_data_ax,'Color','r','Position',[(xm+xM)/2,(ym+yM)/2]);
        app.BottomleftgreenEditField.Value = join(string([xm,", ",ym]));
        app.ToprightblueEditField.Value = join(string([xM,", ",yM]));
        addlistener(app.roibl,'ROIMoved',@(src,evt) set_region_callback(src,evt,app.BottomleftgreenEditField,@app.MaprotationCCWEditFieldValueChanged));
        addlistener(app.roitr,'ROIMoved',@(src,evt) set_region_callback(src,evt,app.ToprightblueEditField,@app.MaprotationCCWEditFieldValueChanged));
        addlistener(app.roifire,'ROIMoved',@(src,evt) set_region_callback(src,evt,app.ToprightblueEditField,@app.MaprotationCCWEditFieldValueChanged));
        app.MaprotationCCWEditField.Value = string(shape_opts.north_dir*180/pi);
        app.MaprotationCCWEditFieldValueChanged(0);
        app.NorthdirectionGauge.Value = -shape_opts.north_dir; % rad
        app.original_data_ax.HandleVisibility = 'off';
      else
        app.TabGroup.SelectedTab = app.SimulationTab;
      end
    end

    % Button pushed function: InitializeButton
    function initialize_simulation(app, event)
      app.InitializefirstLampLabel.Text = "Initializing";
      app.InitializefirstLamp.Color = [1 0 0];
      flag.wind_average = app.windaverageCheckBox.Value;
      flag.debug = 0;
      flag.ns = app.SpreadingEditField_2.Value;
      flag.nf = app.FlyingEditField_2.Value;

      app.step_end = app.SimstepEditField.Value; % Description
      app.unum = app.FirefightersEditField.Value;
      if isempty(app.map.W)
        app.map.set_target();
      end
      wind_data = app.WinddataEditField.Value;
      app.map.setup_wind(wind_data);
      app.map.set_gridcell_model();

      app.step_end = 240;
      app.TimeSlider.Limits = [0,app.SimstepEditField.Value];
      app.InitializefirstLampLabel.Text = "Ready";
      app.InitializefirstLamp.Color = [0 1 0];
    end

    % Value changed function: StartButton
    function Start(app, event)
      value = app.StartButton.Text;
      if strcmp(value,"Start")
        Il = app.map.ti;
        nx = app.map.nx;
        ny = app.map.ny;
        unum = app.unum;
        step_end = app.step_end;
        N = nx*ny;

        init_fx=54;
        init_fy=9;
        init_I = sparse(N,1);
        r=randi(1,numel(init_fx),numel(init_fy));
        W_vec = reshape(app.map.W,N,1);
        W_vec2 = logical(mod(W_vec,0));     %W_vecを0と1のみのlogical値に変換．これをしないと初期引火点が何時までも消火しない
        init_I(ny*(init_fx-1)+init_fy) = 1.*W_vec2(ny*(init_fx-1)+init_fy);
        init_R = sparse(N,1);
        app.map.initialize_each_grid(init_I,init_R);
        app.logger.k=zeros(1,step_end);
        app.logger.S(:,1) = app.map.S(:);
        app.logger.I(:,1) = app.map.I(:);
        app.logger.R(:,1) = app.map.R(:);
        app.logger.U(:,1) = app.map.U(:);
        app.logger.P = sparse(N,step_end);
        p = app.map.arranged_position([nx,ny],unum,2,0);
        p = (p(1,:)'-1)*ny + p(2,:)';% init position indices
        pt = zeros(unum,1);
        k = 1;
        X = app.map.W;
        Xm = min(app.map.W,[],'all');
        XM = max(app.map.W,[],'all');
        while (k <= step_end) && sum(find(app.map.I))
          app.TimeSlider.Value = k;
          app.TimeSliderLabel.Text = "k : " + k;
          app.map.draw_state(app.map,app.UIAxes);
          wi = app.map.wind_time(1);
          E = app.map.EF{wi} + app.map.ES{wi};
          fi= find(app.map.I);% 燃えているマップのインデックス
          tmpX = X(fi)+(Il-app.map.I(fi));% 燃えているマップの重要度：X = V, Wどちらでも縦ベクトルになる．

          % target point t 選択：重要度の高い順にtunum個選択
          tunum = min(unum,length(fi));
          u = zeros(tunum,1);% extinguish input indices
          for iu=1:tunum
            [~,I]= max(tmpX);
            u(iu) = fi(I);%
            tmpX(I)=Xm;% 選択済みのものを最低rankに設定
          end
          U = sparse(u,1,1,N,1);

          app.map.next_step_func(U,wi);% obj 更新
          % log
          app.logger.k(k)=k;
          app.logger.S(:,k) = app.map.S(:);
          app.logger.I(:,k) = app.map.I(:);
          app.logger.R(:,k) = app.map.R(:);
          app.logger.U(:,k) = app.map.U(:);
          app.logger.UF(:,k) = app.map.vf(:); % 飛び火の発生回数の保存 Logger(i).UFで確認
          k = k+1;

          if k == 9
            unum = unum + 16;
          elseif k == 25
            unum = unum + 7;
          elseif k == 33
            unum = unum + 19;
          elseif  k == 42
            unum = unum + 4;
          elseif  k == 69
            unum = unum + 3;
          elseif  k == 120
            unum = unum + 12;
          end
        end
      end
    end

    % Value changed function: MaprotationCCWEditField
    function MaprotationCCWEditFieldValueChanged(app, event)
      value = double(string(app.MaprotationCCWEditField.Value));
      view(app.original_data_ax,atan(tan(-value*pi/180)*app.shape_ratio)*180/pi,90);
      north_dir = value*pi/180; % rad
      app.NorthdirectionGauge.Value = -north_dir;
      dlist = [];
      for i = 1:length(app.original_data_ax.Children)
        if strcmp(class(app.original_data_ax.Children(i)),'matlab.graphics.primitive.Patch')
          dlist = [dlist, i];
        end
      end
      delete(app.original_data_ax.Children(dlist));
      cn = cos(-north_dir);
      sn = sin(-north_dir);
      sp0 = app.roibl.Position';
      sp2 = app.roitr.Position';
      lxy = [cn sn;-sn cn]*(sp2-sp0);
      sp1 = sp0 + lxy(1)*[cn;sn];
      sp3 = sp0 + lxy(2)*[-sn;cn];
      app.ps = [sp0';sp1';sp2';sp3'];
      patch(app.original_data_ax,'Faces',[1,2,3,4],'Vertices',app.ps,...
        'EdgeColor','green','FaceColor','none','LineWidth',2);
      app.original_data_ax.Children(end+1) = app.original_data_ax.Children(1);
    end

    % Value changed function: BottomleftgreenEditField
    function BottomleftgreenEditFieldValueChanged(app, event)
      app.MaprotationCCWEditFieldValueChanged(0);
    end

    % Value changed function: ToprightblueEditField
    function ToprightblueEditFieldValueChanged(app, event)
      app.MaprotationCCWEditFieldValueChanged(0);
    end

    % Button pushed function: SetmapButton
    function SetmapButtonPushed(app, event)
      app.map.shape_opts.start_point = app.ps(1,:)-app.shape_min;
      app.map.shape_opts.north_dir = -app.NorthdirectionGauge.Value;
      app.map.shape_opts.map_size = vecnorm([(app.ps(2,:)-app.ps(1,:))',(app.ps(4,:)-app.ps(1,:))']);
      app.NorthdirectionGauge.Visible = 'on';
      app.TabGroup.SelectedTab = app.SimulationTab;
      %app.NorthdirectionGaugeLabel.Text = "North direction";
      app.map.set_target();
      app.nxEditField.Value = app.map.nx;
      app.nyEditField.Value = app.map.ny;
      app.map.plot_W(app.UIAxes);
    end

    % Value changed function: nxEditField
    function nxEditFieldValueChanged(app, event)
      app.map.nx = app.nxEditField.Value;
      app.map.plot_W(app.UIAxes);      
    end

    % Value changed function: nyEditField
    function nyEditFieldValueChanged(app, event)
      app.map.ny = app.nyEditField.Value;
      app.map.plot_W(app.UIAxes);      
    end

    % Changes arrangement of the app based on UIFigure width
    function updateAppLayout(app, event)
      currentFigureWidth = app.UIFigure.Position(3);
      if(currentFigureWidth <= app.onePanelWidth)
          % Change to a 2x1 grid
          app.GridLayout.RowHeight = {565, 565};
          app.GridLayout.ColumnWidth = {'1x'};
          app.RightPanel.Layout.Row = 2;
          app.RightPanel.Layout.Column = 1;
      else
          % Change to a 1x2 grid
          app.GridLayout.RowHeight = {'1x'};
          app.GridLayout.ColumnWidth = {220, '1x'};
          app.RightPanel.Layout.Row = 1;
          app.RightPanel.Layout.Column = 2;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      colormap(app.UIFigure, 'parula');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
      app.GridLayout = uigridlayout(app.UIFigure);
      app.LeftPanel = uipanel(app.GridLayout);
      app.windaverageCheckBox = uicheckbox(app.LeftPanel);
      app.windaverageCheckBox.Text = 'wind average';
      app.CheckBox = uicheckbox(app.LeftPanel);
      app.FlyingEditField_2Label = uilabel(app.LeftPanel);
      app.FlyingEditField_2Label.Text = 'Flying';
      app.FlyingEditField_2 = uieditfield(app.LeftPanel, 'text');
      app.FlyingEditField_2.Value = '[0.075,1.2,1.5]';
      app.SpreadingEditField_2Label = uilabel(app.LeftPanel);
      app.SpreadingEditField_2Label.Text = 'Spreading';
      app.SpreadingEditField_2 = uieditfield(app.LeftPanel, 'text');
      app.SpreadingEditField_2.Value = '[0.07,0.3,2.4]';
      app.NorthdirectionGauge = uigauge(app.LeftPanel, 'semicircular');
      app.NorthdirectionGauge.Limits = [-1.57 1.57];
      app.NorthdirectionGauge.MajorTicks = [-1.57 2.7e-15 1.57];
      app.NorthdirectionGauge.MajorTickLabels = {''};
      app.FirefightersEditFieldLabel = uilabel(app.LeftPanel);
      app.FirefightersEditFieldLabel.Text = 'Fire fighters';
      app.FirefightersEditField = uieditfield(app.LeftPanel, 'numeric');
      app.FirefightersEditField.Value = 1;
      app.SimstepEditFieldLabel = uilabel(app.LeftPanel);
      app.SimstepEditFieldLabel.Text = 'Sim step';
      app.SimstepEditField = uieditfield(app.LeftPanel, 'numeric');
      app.SimstepEditField.Value = 240;
      app.WinddataEditFieldLabel = uilabel(app.LeftPanel);
      app.WinddataEditFieldLabel.Text = 'Wind data';
      app.WinddataEditField = uieditfield(app.LeftPanel, 'text');
      app.WinddataEditField.Value = 'data_ito.csv';
      app.ShapefileLabel = uilabel(app.LeftPanel);
      app.ShapefileLabel.Text = 'Shape file : ';
      app.mapsizeLabel = uilabel(app.LeftPanel);
      app.mapsizeLabel.Text = 'map size : ';
      app.nxEditFieldLabel = uilabel(app.LeftPanel);
      app.nxEditFieldLabel.Text = 'nx';
      app.nxEditField = uieditfield(app.LeftPanel, 'numeric');
      app.nxEditField.ValueChangedFcn = createCallbackFcn(app, @nxEditFieldValueChanged, true);
      app.nyEditFieldLabel = uilabel(app.LeftPanel);
      app.nyEditFieldLabel.Text = 'ny';
      app.nyEditField = uieditfield(app.LeftPanel, 'numeric');
      app.nyEditField.ValueChangedFcn = createCallbackFcn(app, @nyEditFieldValueChanged, true);
      app.RightPanel = uipanel(app.GridLayout);
      app.TabGroup = uitabgroup(app.RightPanel);
      app.SettingTab = uitab(app.TabGroup);
      app.SettingTab.Title = 'Setting';
      app.original_data_ax = uiaxes(app.SettingTab);
      title(app.original_data_ax, 'Title')
      xlabel(app.original_data_ax, 'X')
      ylabel(app.original_data_ax, 'Y')
      zlabel(app.original_data_ax, 'Z')
      app.BottomleftgreenEditFieldLabel = uilabel(app.SettingTab);
      app.BottomleftgreenEditFieldLabel.Text = 'Bottom left (green)';
      app.BottomleftgreenEditField = uieditfield(app.SettingTab, 'text');
      app.BottomleftgreenEditField.ValueChangedFcn = createCallbackFcn(app, @BottomleftgreenEditFieldValueChanged, true);
      app.ToprightblueEditField = uieditfield(app.SettingTab, 'text');
      app.ToprightblueEditField.ValueChangedFcn = createCallbackFcn(app, @ToprightblueEditFieldValueChanged, true);
      app.ToprightblueEditFieldLabel = uilabel(app.SettingTab);
      app.ToprightblueEditFieldLabel.Text = 'Top right (blue)';
      app.MaprotationCCWEditFieldLabel = uilabel(app.SettingTab);
      app.MaprotationCCWEditFieldLabel.Text = 'Map rotation (CCW)';
      app.MaprotationCCWEditField = uieditfield(app.SettingTab, 'text');
      app.MaprotationCCWEditField.ValueChangedFcn = createCallbackFcn(app, @MaprotationCCWEditFieldValueChanged, true);
      app.SetmapButton = uibutton(app.SettingTab, 'push');
      app.SetmapButton.ButtonPushedFcn = createCallbackFcn(app, @SetmapButtonPushed, true);
      app.SetmapButton.Text = 'Set map';
      app.IgnitionpointredEditField = uieditfield(app.SettingTab, 'text');
      app.IgnitionpointredEditFieldLabel = uilabel(app.SettingTab);
      app.IgnitionpointredEditFieldLabel.Text = 'Ignition point (red)';
      app.SimulationTab = uitab(app.TabGroup);
      app.SimulationTab.Title = 'Simulation';
      app.UIAxes2 = uiaxes(app.SimulationTab);
      title(app.UIAxes2, 'Flying fire')
      zlabel(app.UIAxes2, 'Z')
      app.UIAxes3 = uiaxes(app.SimulationTab);
      title(app.UIAxes3, 'Spreading fire')
      zlabel(app.UIAxes3, 'Z')
      app.UIAxes = uiaxes(app.SimulationTab);
      title(app.UIAxes, 'Title')
      xlabel(app.UIAxes, 'X')
      ylabel(app.UIAxes, 'Y')
      zlabel(app.UIAxes, 'Z')
      app.SwitchmainviewButtonGroup = uibuttongroup(app.SimulationTab);
      app.SwitchmainviewButtonGroup.Title = 'Switch main view ';
      app.FireStatusButton = uitogglebutton(app.SwitchmainviewButtonGroup);
      app.FireStatusButton.Text = 'Fire Status';
      app.FireStatusButton.Value = true;
      app.GridweightButton = uitogglebutton(app.SwitchmainviewButtonGroup);
      app.GridweightButton.Text = 'Grid weight';
      app.Button3 = uitogglebutton(app.SwitchmainviewButtonGroup);
      app.Button3.Text = 'Button3';
      app.TimeSliderLabel = uilabel(app.SimulationTab);
      app.TimeSliderLabel.Text = 'Time';
      app.TimeSlider = uislider(app.SimulationTab);
      app.InitializefirstLampLabel = uilabel(app.SimulationTab);
      app.InitializefirstLampLabel.Text = 'Initialize first';
      app.InitializefirstLamp = uilamp(app.SimulationTab);
      app.StartButton = uibutton(app.SimulationTab, 'state');
      app.StartButton.ValueChangedFcn = createCallbackFcn(app, @Start, true);
      app.StartButton.Text = 'Start';
      app.InitializeButton = uibutton(app.SimulationTab, 'push');
      app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @initialize_simulation, true);
      app.InitializeButton.Text = 'Initialize';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = FireSimulation(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end